classdef Tugas4_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ClassificationResultLabel  matlab.ui.control.Label
        ClassificationLabel        matlab.ui.control.Label
        ControlPanel               matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        ResetButton                matlab.ui.control.Button
        ClassifyImageButton        matlab.ui.control.Button
        LoadImageButton            matlab.ui.control.Button
        UIAxesOriginal             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage    % RGB or grayscale original image
        GrayImage        % Grayscale version of OriginalImage
        EdgeImage        % Binary or grayscale edge image
        MaskedImage      % Masked image for segmentation control
        SegmentedImage   % Segmentation result
    end
    
    methods (Access = private)
        
        function E = edgeLaplace(app, f, T)        
            % Standard 3x3 Laplacian mask
            h = [ 0  1  0;
                  1 -4  1;
                  0  1  0 ];
        
            % Convolution with Laplace mask
            g = imfilter(f, h, 'replicate', 'conv');
        
            g = abs(g);  % magnitude response
            g = g ./ max(g(:) + eps);  % avoid divide by zero
        
            E = g > T;
        
            % Output is logical
            E = logical(E);
        end

        function E = edgeLoG(app, f, T)
            % Choose sigma and kernel size
            sigma = app.sigmaEditField.Value;
            if (isempty(sigma))
                sigma = 1.0;
            end
            ksize = 5;  % odd size (5x5)
            
            % Build Gaussian kernel manually 
            half = floor(ksize/2);
            [x, y] = meshgrid(-half:half, -half:half);
            G = exp(-(x.^2 + y.^2) / (2*sigma^2));
            G = G / sum(G(:));  % normalize
            
            % Smooth the image
            smoothImg = imfilter(f, G, 'replicate', 'conv');
        
            % Standard 3x3 Laplacian mask
            h = [ 0  1  0;
                  1 -4  1;
                  0  1  0 ];
        
            % Apply Laplacian to smoothed image
            g = imfilter(smoothImg, h, 'replicate', 'conv');

            g = abs(g);
            g = g ./ (max(g(:)) + eps);  % normalize to [0,1]
        
            E = g > T;
            E = logical(E);
        end

        function E = edgeSobel(app, f, T, c)
            % Create mask Sx and Sy
            Sx = [ -1 0 1;
                   -c 0 c;
                   -1 0 1 ];
            
            Sy = [  1  c  1;
                    0  0  0;
                   -1 -c -1 ];

            % Apply mask
            Jx = imfilter(f, Sx, 'replicate', 'conv');
            Jy = imfilter(f, Sy, 'replicate', 'conv');

            % Add result
            g = sqrt(Jx.^2 + Jy.^2);
            
            % Check edge case
            g = abs(g);  % magnitude response
            g = g ./ max(g(:) + eps);  % avoid divide by zero
        
            E = g > T;
        
            % Output is logical
            E = logical(E);
        end

        function E = edgePrewitt(app, f, T)
            % Create mask Sx and Sy
            Sx = [ -1 0 1;
                   -1 0 1;
                   -1 0 1 ];
            
            Sy = [  1  1  1;
                    0  0  0;
                   -1 -1 -1 ];

            % Apply mask
            Jx = imfilter(f, Sx, 'replicate', 'conv');
            Jy = imfilter(f, Sy, 'replicate', 'conv');

            % Add result
            g = sqrt(Jx.^2 + Jy.^2);
            
            % Check edge case
            g = abs(g);  % magnitude response
            g = g ./ max(g(:) + eps);  % avoid divide by zero
        
            E = g > T;
        
            % Output is logical
            E = logical(E);
        end

        function E = edgeRoberts(app, f, T)
            % Create mask R+ (Rp) and R- (Rm)
            Rp = [ 1  0;
                   0 -1;];
            
            Rm = [  0  1;
                   -1  0;];

            % Apply mask
            Jx = imfilter(f, Rp, 'replicate', 'conv');
            Jy = imfilter(f, Rm, 'replicate', 'conv');

            % Add result
            g = sqrt(Jx.^2 + Jy.^2);
            % g = abs(Jx) + abs(Jy);
            
            % Check edge case
            g = abs(g);  % magnitude response
            g = g ./ max(g(:) + eps);  % avoid divide by zero
        
            E = g > T;
        
            % Output is logical
            E = logical(E);
        end

        function E = edgeCanny(app, f, T)        
            sigma = app.sigmaEditField.Value;

            isAutoT = app.AutoThresholdCheckBox.Value;
            
            if ((isempty(T) || isAutoT) && isempty(sigma))
                E = edge(f, 'canny');
                return
            end

            if (isempty(T) || isAutoT)
                E = edge(f, 'canny', [], sigma);
                return
            end

            if (isempty(sigma))
                if (isAutoT)
                    E = edge(f, 'canny');
                else
                    E = edge(f, 'canny', T);
                end
                return;
            end
            
            E = edge(f, 'canny', T, sigma);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function LoadImageButtonPushed(app, event)
            [fname, fpath] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, ...
                                       'Select Input Image');
            if isequal(fname,0)
                return; % user cancelled
            end
            
            fullPath = fullfile(fpath, fname);
            img = imread(fullPath);
            
            app.OriginalImage = img;
            
            % Convert to grayscale if needed
            if size(img, 3) == 3
                app.GrayImage = rgb2gray(img);
            else
                app.GrayImage = img;
            end
            
            % Display original
            imshow(app.OriginalImage, 'Parent', app.UIAxesOriginal);

            % Adjust AreaOpen Slider & EditField
            maxVal = numel(app.GrayImage);

            % clamp existing value
            v = app.AreaOpenSlider.Value;
            v = min(max(v, 0), maxVal);

            app.AreaOpenSlider.Limits = [0 numel(app.GrayImage)];
            app.AreaOpenSlider.Value = v;

            app.AreaOpenEditField.Limits = [0 numel(app.GrayImage)];
            app.AreaOpenEditField.Value = v;
        end

        % Callback function: not associated with a component
        function ClassifyImageButtonPushed(app, event)
            if isempty(app.GrayImage)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                return;
            end
            
            op = app.OperatorDropDown.Value;
            T = app.ThresholdSlider.Value; % threshold in [0,1]
            
            % Normalize image to double [0,1] just in case
            f = im2double(app.GrayImage);
            
            switch op
                case 'Laplace'
                    app.EdgeImage = app.edgeLaplace(f, T);
                    
                case 'LoG'
                    app.EdgeImage = app.edgeLoG(f, T);
                case 'Sobel'
                    c = app.cconstantEditField.Value;
                    % Make sure c constant exist
                    if isempty(c)
                        uialert(app.UIFigure, 'Please input c constant first.', 'No C Constant');
                        return;
                    end
                    app.EdgeImage = app.edgeSobel(f, T, c);

                case 'Prewitt'
                    app.EdgeImage = app.edgePrewitt(f, T);

                case 'Roberts'
                    app.EdgeImage = app.edgeRoberts(f, T);

                case 'Canny'
                    app.EdgeImage = app.edgeCanny(f, T);
                    
                otherwise
                    uialert(app.UIFigure, 'Unknown operator.', 'Error');
                    return;
            end
            
            % Show edge image
            imshow(app.EdgeImage, 'Parent', app.UIAxesEdges);
        end

        % Callback function: not associated with a component
        function SegmentButtonPushed(app, event)
            % Make sure we have edges
            if isempty(app.EdgeImage)
                uialert(app.UIFigure, 'Please detect edges first.', 'No Edge Image');
                return;
            end
        
            edgeImg = app.EdgeImage;
        
            % Close edges by radius strelC
            strelC = app.imcloseconstantEditField.Value;
            se = strel('disk', strelC);
            % closedEdges = imdilate(edgeImg, se);
            closedEdges = imclose(edgeImg, se);

            % Fill up closed edges
            filled = imfill(closedEdges, 'holes');
        
            X = app.AreaOpenSlider.Value;

            % Keep objects with area >= X pixels
            mask = bwareaopen(filled, X);

            app.MaskedImage = mask;
            imshow(app.MaskedImage, 'Parent', app.UIAxesMasked);
            title(app.UIAxesMasked, 'Masked Image');
            % figure, imshow();
        
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Original image is not available.', 'Error');
                return;
            end
        
            rgb = app.OriginalImage;
            % Ensure we have an RGB image for display
            if size(rgb, 3) == 1
                rgb = cat(3, rgb, rgb, rgb);
            end
        
            segmented = rgb;
            % Set background (mask == 0) to black
            for c = 1:3
                temp = segmented(:, :, c);
                temp(~mask) = 0;
                segmented(:, :, c) = temp;
            end
        
            % Store and display the result
            app.SegmentedImage = segmented;
            imshow(app.SegmentedImage, 'Parent', app.UIAxesSegmented);
            title(app.UIAxesSegmented, 'Segmented Object');
        end

        % Callback function: not associated with a component
        function ResetButtonPushed(app, event)
            app.OriginalImage  = [];
            app.GrayImage      = [];
            app.EdgeImage      = [];
            app.MaskedImage    = [];
            app.SegmentedImage = [];
            
            cla(app.UIAxesOriginal, 'reset');
            cla(app.UIAxesEdges, 'reset');
            cla(app.UIAxesMasked, 'reset');
            cla(app.UIAxesSegmented, 'reset');
            
            title(app.UIAxesOriginal, 'Original Image');
            title(app.UIAxesEdges, 'Edge Image');
            title(app.UIAxesMasked, 'Masked Image');
            title(app.UIAxesSegmented, 'Segmented Object');
            
            app.OperatorDropDown.Value = 'Sobel';
            app.ThresholdSlider.Value  = 0.2;
            app.ThresholdEditField.Value = 0.2;

            app.AutoThresholdCheckBox.Enable = "off";
            app.sigmaEditField.Enable = "off";
        end

        % Callback function: not associated with a component
        function OperatorDropDownValueChanged(app, event)
            app.cconstantEditField.Enable = "off";
            app.sigmaEditField.Enable = "off";
            app.AutoThresholdCheckBox.Enable = "off";

            op = app.OperatorDropDown.Value;

            % Choose a suggested default threshold depending on operator
            switch op
                case 'Laplace'
                    defaultT = 0.20;
                case 'LoG'
                    defaultT = 0.20;
                    app.sigmaEditField.Enable = "on";
                case 'Sobel'
                    defaultT = 0.15;
                    app.cconstantEditField.Enable = "on";
                case {'Prewitt', 'Roberts'}
                    defaultT = 0.15;
                case 'Canny'
                    defaultT = 0.10;
                    app.sigmaEditField.Enable = "on";
                    app.AutoThresholdCheckBox.Enable = "on";
                otherwise
                    defaultT = app.ThresholdSlider.Value;
            end
        
            % Keep it in [0,1] just to be safe
            defaultT = max(0, min(1, defaultT));
        
            % Update both controls so they stay in sync
            app.ThresholdSlider.Value      = defaultT;
            app.ThresholdEditField.Value   = defaultT;
        end

        % Callback function: not associated with a component
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.ThresholdEditField.Value = value;
        end

        % Callback function: not associated with a component
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;

            % Clip 
            value = max(0, min(1, value));
            app.ThresholdEditField.Value = value;
            app.ThresholdSlider.Value = value;
        end

        % Callback function: not associated with a component
        function AreaOpenSliderValueChanged(app, event)
            value = app.AreaOpenSlider.Value;
            app.AreaOpenEditField.Value = value;
        end

        % Callback function: not associated with a component
        function AreaOpenEditFieldValueChanged(app, event)
            value = app.AreaOpenEditField.Value;

            % Clip 
            value = max(0, min(numel(app.GrayImage), value));
            app.AreaOpenEditField.Value = value;
            app.AreaOpenSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesOriginal = uiaxes(app.GridLayout);
            title(app.UIAxesOriginal, 'Original Image')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout2 = uigridlayout(app.ControlPanel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.Text = 'Load Image';
            app.ClassifyImageButton = uibutton(app.GridLayout2, 'push');
            app.ClassifyImageButton.Text = 'Classify Image';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.Text = 'Reset';
            app.ClassificationLabel = uilabel(app.GridLayout);
            app.ClassificationLabel.Text = 'Classification';
            app.ClassificationResultLabel = uilabel(app.GridLayout);
            app.ClassificationResultLabel.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas4_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end